classdef ImageDeblur < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        radiusSliderLabel        matlab.ui.control.Label
        radiusSlider             matlab.ui.control.Slider
        smoothSliderLabel        matlab.ui.control.Label
        smoothSlider             matlab.ui.control.Slider
        radiusLabel              matlab.ui.control.Label
        smoothLabel              matlab.ui.control.Label
        saveButton               matlab.ui.control.Button
        avoidringingSwitchLabel  matlab.ui.control.Label
        avoidringingSwitch       matlab.ui.control.Switch
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global G;
            global F;
            addpath('../');
            addpath('../dataset');
            addpath('../src');
            app.UIFigure.Name = "ImageDeblur";
            G = imread('image.jpg');
            
            r = estimateRadius(G, false);
            app.radiusSlider.Limits = [0, 20];
            app.smoothSlider.Limits = [0, 100];
            
            app.radiusSlider.Value = r;
            app.smoothSlider.Value = 45;
            
            F = deblur(G, app.radiusSlider.Value, app.smoothSlider.Value, app.avoidringingSwitch.Value);
            imshowpair(G, F, 'montage', 'Scaling', 'None', 'Parent', app.UIAxes);
            app.radiusLabel.Text = num2str(app.radiusSlider.Value);
            app.smoothLabel.Text = num2str(app.smoothSlider.Value);
            drawnow;
        end

        % Value changed function: avoidringingSwitch, radiusSlider, 
        % smoothSlider
        function radiusSliderValueChanged(app, event)
            global G;
            global F;
            F = deblur(G, app.radiusSlider.Value, app.smoothSlider.Value, app.avoidringingSwitch.Value);
            imshowpair(G, F, 'montage', 'Scaling', 'None', 'Parent', app.UIAxes);
            app.radiusLabel.Text = num2str(app.radiusSlider.Value);
            app.smoothLabel.Text = num2str(app.smoothSlider.Value);
            drawnow;
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            global F;
            [file,path] = uiputfile('*.jpg');
            imwrite(F, fullfile(path, file))
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '左：原图；右：去模糊')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.radiusSliderLabel = uilabel(app.UIFigure);
            app.radiusSliderLabel.Text = 'radius';
            app.radiusSlider = uislider(app.UIFigure);
            app.radiusSlider.ValueChangedFcn = createCallbackFcn(app, @radiusSliderValueChanged, true);
            app.smoothSliderLabel = uilabel(app.UIFigure);
            app.smoothSliderLabel.Text = 'smooth';
            app.smoothSlider = uislider(app.UIFigure);
            app.smoothSlider.ValueChangedFcn = createCallbackFcn(app, @radiusSliderValueChanged, true);
            app.radiusLabel = uilabel(app.UIFigure);
            app.smoothLabel = uilabel(app.UIFigure);
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.avoidringingSwitchLabel = uilabel(app.UIFigure);
            app.avoidringingSwitchLabel.Text = 'avoid ringing';
            app.avoidringingSwitch = uiswitch(app.UIFigure, 'slider');
            app.avoidringingSwitch.ValueChangedFcn = createCallbackFcn(app, @radiusSliderValueChanged, true);
            app.avoidringingSwitch.Value = 'On';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ImageDeblur

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end