classdef IntensityAdjust < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes    matlab.ui.control.UIAxes
        Label     matlab.ui.control.Label
        Adjust    matlab.ui.control.DropDown
        Label_3   matlab.ui.control.Label
        SliderA   matlab.ui.control.Slider
        Label_2   matlab.ui.control.Label
        SliderB   matlab.ui.control.Slider
        Button    matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global I;
            global J;
            addpath('lib/');
            addpath('../lib/');
            addpath('../src/');
            
            I = imread('../dataset/lena.jpg');
            
            app.UIAxes.Visible = false;
            app.SliderA.Visible = false;
            app.SliderB.Visible = false;
            app.Label_2.Visible = false;
            app.Label_3.Visible = false;
            app.UIFigure.Name = 'IntensityAdjust';
            
            J = histAdjust(I);
            imshowpair(I, J, 'montage', 'Parent', app.UIAxes, 'Scaling', 'none');
        end

        % Callback function
        function SliderAValueChanging(app, event)
            
        end

        % Value changed function: Adjust
        function AdjustValueChanged(app, event)
            value = app.Adjust.Value;
            global I;
            global J;
            
            app.Label_3.Text = 'α';
            switch(value)
                case '1'
                    J = histAdjust(I);
                    app.SliderA.Visible = false;
                    app.SliderB.Visible = false;
                    app.Label_2.Visible = false;
                    app.Label_3.Visible = false;
                case '2'
                    app.SliderA.Visible = true;
                    app.SliderB.Visible = true;
                    app.Label_2.Visible = true;
                    app.Label_3.Visible = true;
                    app.SliderA.Limits = [0, 10];
                    app.SliderB.Limits = [0, 1];
                case '3'
                    app.SliderA.Visible = true;
                    app.SliderB.Visible = false;
                    app.Label_2.Visible = false;
                    app.Label_3.Visible = true;
                    app.SliderA.Limits = [0, 2];
                    app.Label_3.Text = 'γ';
                case '4'
                    app.SliderA.Visible = true;
                    app.SliderB.Visible = false;
                    app.Label_2.Visible = false;
                    app.Label_3.Visible = true;
                    app.SliderA.Limits = [0, 2];
                case '5'
                    app.SliderA.Visible = true;
                    app.SliderB.Visible = false;
                    app.Label_2.Visible = false;
                    app.Label_3.Visible = true;
                    app.SliderA.Limits = [0, 40];
                case '6'
                    app.SliderA.Visible = false;
                    app.SliderB.Visible = false;
                    app.Label_2.Visible = false;
                    app.Label_3.Visible = false;
                otherwise
                    disp(value);
            end
            SliderAValueChanged(app, event);
        end

        % Value changed function: SliderA, SliderB
        function SliderAValueChanged(app, event)

            global J;
            global I;
            switch (app.Adjust.Value)
                case '1'
                    J = histAdjust(I);
                case '2'
                    J = linearAdjust(I, app.SliderA.Value, app.SliderB.Value);
                case '3'
                    J = gammaAdjust(I, app.SliderA.Value);
                case '4'
                    J = sineAdjust(I, app.SliderA.Value);
                case '5'
                    J = logAdjust(I, app.SliderA.Value);
                case '6'
                    J = autoAdjust(I);
                otherwise
                    disp(app.Adjust.Value);
            end
            imshowpair(I, J, 'montage', 'Parent', app.UIAxes, 'Scaling', 'none');
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            global I;
            
            [file, path] = uigetfile('*.jpg;*.bmp;*.png', '请选择图片');
            I = imread(fullfile(path, file));
            if ndims(I) == 3
                I = rgb2gray(I);
            end
            SliderAValueChanged(app, event);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '左图：亮度调整前图片；右图：亮度调整后图片')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '亮度调整算法';
            app.Adjust = uidropdown(app.UIFigure);
            app.Adjust.Items = {'直方图均衡', '线性校正', 'Gamma校正', '正弦校正', '对数校正', '自动调整'};
            app.Adjust.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.Adjust.ValueChangedFcn = createCallbackFcn(app, @AdjustValueChanged, true);
            app.Adjust.Value = '1';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'α';
            app.SliderA = uislider(app.UIFigure);
            app.SliderA.Limits = [0 1];
            app.SliderA.ValueChangedFcn = createCallbackFcn(app, @SliderAValueChanged, true);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'β';
            app.SliderB = uislider(app.UIFigure);
            app.SliderB.Limits = [0 1];
            app.SliderB.ValueChangedFcn = createCallbackFcn(app, @SliderAValueChanged, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '载入图片';
        end
    end

    methods (Access = public)

        % Construct app
        function app = IntensityAdjust

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end