classdef CT_Process < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        TabGroup       matlab.ui.container.TabGroup
        start          matlab.ui.container.Tab
        UIAxes1        matlab.ui.control.UIAxes
        spine          matlab.ui.container.Tab
        Panel          matlab.ui.container.Panel
        aSliderLabel   matlab.ui.control.Label
        a              matlab.ui.control.Slider
        Slider_2Label  matlab.ui.control.Label
        gamma          matlab.ui.control.Slider
        SliderLabel    matlab.ui.control.Label
        Radius         matlab.ui.control.Slider
        UIAxes21       matlab.ui.control.UIAxes
        UIAxes22       matlab.ui.control.UIAxes
        fixer          matlab.ui.container.Tab
        UIAxes31       matlab.ui.control.UIAxes
        UIAxes32       matlab.ui.control.UIAxes
        finish         matlab.ui.container.Tab
        UIAxes41       matlab.ui.control.UIAxes
        UIAxes42       matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global G; % Original
            global offset;
            
            app.UIFigure.Name = "CT Image Process";
            addpath('../dataset/');
            addpath('../src/');
            
            
            % show original image
            G = im2double(rgb2gray(imread('image.jpg')));
            Im = imshow(G, 'Parent', app.UIAxes1);
            
            RadiusValueChanged(app, -1);
            imshow(G, 'Parent', app.UIAxes21, 'Border', 'tight');
            offset = [300, 100, 300];
            TabGroupSelectionChanged(app, -1);
            
            
            % adjust limits
            app.UIAxes1.XLim = [0, Im.XData(2)];
            app.UIAxes21.XLim = [0, Im.XData(2)];
            app.UIAxes22.XLim = [0, Im.XData(2)];
            app.UIAxes31.XLim = [0, Im.XData(2)];
            app.UIAxes32.XLim = [0, Im.XData(2)];
            app.UIAxes41.XLim = [0, Im.XData(2)];
            app.UIAxes42.XLim = [0, Im.XData(2)];
        end

        % Value changed function: Radius, a, gamma
        function RadiusValueChanged(app, event)
            global H;
            global G;
            global H_changed;
            
            % remove spine
            H = removeSpine(G, app.Radius.Value, app.gamma.Value, app.a.Value);
            imshow(H, 'Parent', app.UIAxes22);
            H_changed = true;
            imwrite(H, '../results/remove_spine.jpg');
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            global H_changed;
            global J;
            global G;
            global H;
            global I;
            global offset;
            if H_changed
                % detect fixers
                [fixers, masks, lineMasks] = detect(G);
                D = drawAllFixers(H, fixers, false);
                imshow(D, 'Parent', app.UIAxes31);
                
                % adjust intensity
                I = H;
                for u = 1 : 3
                    I = intensityAdjust(I, masks{u}, lineMasks{u}, offset(u));
                end
                
                % remove fixers
                J = removeFixers(I, lineMasks,...
                    [18, 17;
                    15, 15;
                    15, 15],...
                    [1000, 1300;
                    inf, inf;
                    1100, 1000]);
                
                imshow(J, 'Parent', app.UIAxes32);
                
                % result
                imshow(G, 'Parent', app.UIAxes41);
                imshow(J, 'Parent', app.UIAxes42);
                H_changed = false;
                imwrite(J, '../results/remove_fixers.jpg');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.start = uitab(app.TabGroup);
            app.start.Title = '待处理图像';
            app.UIAxes1 = uiaxes(app.start);
            title(app.UIAxes1, '待处理图像')
            xlabel(app.UIAxes1, '')
            ylabel(app.UIAxes1, '')
            app.spine = uitab(app.TabGroup);
            app.spine.Title = '脊柱去除';
            app.Panel = uipanel(app.spine);
            app.Panel.Title = '亮度调节';
            app.aSliderLabel = uilabel(app.Panel);
            app.aSliderLabel.Text = 'a';
            app.a = uislider(app.Panel);
            app.a.Limits = [0 3];
            app.a.ValueChangedFcn = createCallbackFcn(app, @RadiusValueChanged, true);
            app.a.Value = 1.1;
            app.Slider_2Label = uilabel(app.Panel);
            app.Slider_2Label.Text = 'γ';
            app.gamma = uislider(app.Panel);
            app.gamma.Limits = [0 3];
            app.gamma.ValueChangedFcn = createCallbackFcn(app, @RadiusValueChanged, true);
            app.gamma.Value = 0.7;
            app.SliderLabel = uilabel(app.spine);
            app.SliderLabel.Text = '滤波半径';
            app.Radius = uislider(app.spine);
            app.Radius.Limits = [0 50];
            app.Radius.ValueChangedFcn = createCallbackFcn(app, @RadiusValueChanged, true);
            app.Radius.Value = 15;
            app.UIAxes21 = uiaxes(app.spine);
            title(app.UIAxes21, '脊柱去除前')
            xlabel(app.UIAxes21, '')
            ylabel(app.UIAxes21, '')
            app.UIAxes22 = uiaxes(app.spine);
            title(app.UIAxes22, '脊柱去除后')
            xlabel(app.UIAxes22, '')
            ylabel(app.UIAxes22, '')
            app.fixer = uitab(app.TabGroup);
            app.fixer.Title = '固定装置去除';
            app.UIAxes31 = uiaxes(app.fixer);
            title(app.UIAxes31, '固定装置检测结果')
            xlabel(app.UIAxes31, '')
            ylabel(app.UIAxes31, '')
            app.UIAxes32 = uiaxes(app.fixer);
            title(app.UIAxes32, '固定装置去除后')
            xlabel(app.UIAxes32, '')
            ylabel(app.UIAxes32, '')
            app.finish = uitab(app.TabGroup);
            app.finish.Title = '处理后图像';
            app.UIAxes41 = uiaxes(app.finish);
            title(app.UIAxes41, '待处理图像')
            xlabel(app.UIAxes41, '')
            ylabel(app.UIAxes41, '')
            app.UIAxes42 = uiaxes(app.finish);
            title(app.UIAxes42, '处理后图像')
            xlabel(app.UIAxes42, '')
            ylabel(app.UIAxes42, '')
        end
    end

    methods (Access = public)

        % Construct app
        function app = CT_Process

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end