classdef Dehazing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes    matlab.ui.control.UIAxes
        Label_2   matlab.ui.control.Label
        Method    matlab.ui.control.DropDown
        Label     matlab.ui.control.Label
        Slider    matlab.ui.control.Slider
        Button    matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global I;
            global J;
            addpath('../src/');
            app.UIFigure.Name = 'Dehazing';
            I = im2double(imread('../dataset/Fog.bmp'));
            J = dehaze_He(I);
            imshowpair(I, J, 'montage', 'Parent', app.UIAxes, 'Scaling', 'none');
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            global I;
            global J;
            switch (app.Method.Value)
                case '1'
                    J = dehaze_He(I, app.Slider.Value);
                case '2'
                    J = dehaze_Kim(I, app.Slider.Value);
            end
            app.UIAxes.reset
            title(app.UIAxes, '左图：去雾前；右图：去雾后');
            app.UIAxes.TitleFontWeight = 'bold';
            
            imshowpair(I, J, 'montage', 'Parent', app.UIAxes, 'Scaling', 'none');
        end

        % Value changed function: Method
        function MethodValueChanged(app, event)
            value = app.Method.Value;
            switch (value)
                case '1'
                    app.Slider.Value = 0.6;
                case '2'
                    app.Slider.Value = 0.8;
            end
            drawnow;
            SliderValueChanged(app, event);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            global I;
            [file, path] = uigetfile('*.jpg;*.bmp;*.png', '请选择图片', '..\dataset\');
            I = im2double(imread(fullfile(path, file))); 
            figure(app.UIFigure);% prevent window from suddenly minimized
            SliderValueChanged(app, event);         
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '左图：去雾前；右图：去雾后')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '去雾算法';
            app.Method = uidropdown(app.UIFigure);
            app.Method.Items = {'暗通道先验（He et al.）', '优化对比度（Kim et al.）'};
            app.Method.ItemsData = {'1', '2'};
            app.Method.ValueChangedFcn = createCallbackFcn(app, @MethodValueChanged, true);
            app.Method.Value = '1';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'γ校正参数';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 1];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 0.6;
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '载入图片';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Dehazing

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end