classdef ImageStitching < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageStitchingUIFigure  matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        pano                    matlab.ui.control.Button
        Label                   matlab.ui.control.Label
        crop                    matlab.ui.control.Switch
        Label_2                 matlab.ui.control.Label
        dataset                 matlab.ui.control.DropDown
        state                   matlab.ui.control.Label
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global dirs;
            global dataset_root;
            global results_root;
            global feature_root;
            global imgs;
            dataset_root = '../dataset/';
            results_root = '../results/';
            feature_root = '../feature/';
            
            addpath('..\src');
            addpath(genpath('..\lib\vlfeat-0.9.21'));
            app.UIAxes.Visible = false;   
            run('vl_setup');
            app.state.Text = '';
            dirs = {'lab', 'main_building', '618'};
            imgs = loadImage([dataset_root  dirs{1}]);  
            montage(imgs, 'Parent', app.UIAxes);
        end

        % Callback function
        function loadButtonPushed(app, event)
            
        end

        % Button pushed function: pano
        function panoButtonPushed(app, event)
            global E;
            global final_H;
            global H; 
            global imgs;
            global dirs;
            global pano;
            global crop_pano;
            global results_root;
            global feature_root;
            try 
                if isnan(imgs)
                    uialert(app.ImageStitchingUIFigure,'请先载入图片','警告');
                return;
                end
            catch
                disp('Successfully Loaded');
                drawnow;
            end
            
            app.state.Text = "Getting SIFT feature";
            drawnow;
            try
                load([feature_root, dirs{str2double(app.dataset.Value)}, '/sift'], 'sift');
                f = sift{1};
                d = sift{2};
            catch
                [f, d] = SIFT(imgs);
                sift{1} = f;
                sift{2} = d;
                save([feature_root, dirs{str2double(app.dataset.Value)}, '/sift'], 'sift');
            end
            
            app.state.Text = "Matching Images";
            drawnow;
            [H, scores, ~] = matchImages(imgs, f, d);    
            [connected, E] = getMST(scores);
            
            has_nbr = find(sum(E));
            
            if isempty(has_nbr)
                uialert(app.ImageStitchingUIFigure,'找不到匹配图像','警告');
                return;
            end
            
            if connected == 0
                uialert(app.ImageStitchingUIFigure,'图像不连通，可能舍去部分图像','警告');
            end
            
            E = E(has_nbr, has_nbr);
            tmp_E = E;
            
            app.state.Text = "Calculating Homography";
            drawnow;
            imgs = imgs(has_nbr);
            root = has_nbr(1);       
            final_H{root} = eye(3);
            getFinalHomography(root);
            
            app.state.Text = "Transforming Images";
            drawnow;
            [pano1, imgs_, center] = getTransformedImgs(imgs);
            imshow(pano1, 'Parent', app.UIAxes);

            E = tmp_E;
            pano = imgs_{center};
            app.state.Text = "Blending Images";
            drawnow;
            stitch(imgs_, center);
            crop_pano = getCroppedPano(pano);
            
            app.state.Text = "Stitching Finished!";
            drawnow;
            if app.crop.Value == '0'
                imshow(pano, 'Parent', app.UIAxes);
            else
                imshow(crop_pano, 'Parent', app.UIAxes);
            end
            
            imwrite(pano1, [results_root dirs{str2double(app.dataset.Value)} '/without_blend.jpg'])
            imwrite(pano, [results_root dirs{str2double(app.dataset.Value)} '/with_blend.jpg']);
            imwrite(crop_pano, [results_root dirs{str2double(app.dataset.Value)} '/after_crop.jpg']);
        end

        % Value changed function: crop
        function cropValueChanged(app, event)
            value = app.crop.Value;
            global pano;
            global crop_pano;
            if value == '0'
                imshow(pano, 'Parent', app.UIAxes);
            else
                imshow(crop_pano, 'Parent', app.UIAxes);
            end
        end

        % Value changed function: dataset
        function datasetValueChanged(app, event)
            app.state.Text = 'Loading Images...';
            global imgs;
            global dirs;
            global dataset_root;
            imgs = loadImage([dataset_root dirs{str2double(app.dataset.Value)}]);         
            montage(imgs, 'Parent', app.UIAxes);
            app.state.Text = 'Finished Loading Images';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageStitchingUIFigure = uifigure;
            app.ImageStitchingUIFigure.Name = 'Image Stitching';
            app.UIAxes = uiaxes(app.ImageStitchingUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.pano = uibutton(app.ImageStitchingUIFigure, 'push');
            app.pano.ButtonPushedFcn = createCallbackFcn(app, @panoButtonPushed, true);
            app.pano.Text = '开始拼接';
            app.Label = uilabel(app.ImageStitchingUIFigure);
            app.Label.Text = '裁剪';
            app.crop = uiswitch(app.ImageStitchingUIFigure, 'slider');
            app.crop.Items = {'否', '是'};
            app.crop.ItemsData = {'0', '1'};
            app.crop.ValueChangedFcn = createCallbackFcn(app, @cropValueChanged, true);
            app.crop.Value = '0';
            app.Label_2 = uilabel(app.ImageStitchingUIFigure);
            app.Label_2.Text = '数据集';
            app.dataset = uidropdown(app.ImageStitchingUIFigure);
            app.dataset.Items = {'实验室', '主楼', '科协活动室'};
            app.dataset.ItemsData = {'1', '2', '3'};
            app.dataset.ValueChangedFcn = createCallbackFcn(app, @datasetValueChanged, true);
            app.dataset.Value = '1';
            app.state = uilabel(app.ImageStitchingUIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = ImageStitching

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageStitchingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageStitchingUIFigure)
        end
    end
end